classdef DSSynth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSSynthApplicationUIFigure    matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TransferFunctionTab           matlab.ui.container.Tab
        PlantPanel                    matlab.ui.container.Panel
        NumeratorEditFieldLabel       matlab.ui.control.Label
        NumeratorEditField            matlab.ui.control.EditField
        DenominatorEditFieldLabel     matlab.ui.control.Label
        DenominatorEditField          matlab.ui.control.EditField
        SampleTimeEditFieldLabel      matlab.ui.control.Label
        SampleTimeEditField           matlab.ui.control.NumericEditField
        ImplementationPanel           matlab.ui.container.Panel
        IntegerSliderLabel            matlab.ui.control.Label
        IntegerSlider                 matlab.ui.control.Slider
        FractionalSliderLabel         matlab.ui.control.Label
        FractionalSlider              matlab.ui.control.Slider
        MaxRangeSpinnerLabel          matlab.ui.control.Label
        MaxRangeSpinner               matlab.ui.control.Spinner
        MinRangeSpinnerLabel          matlab.ui.control.Label
        MinRangeSpinner               matlab.ui.control.Spinner
        SynthesizeButton_3            matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        SynthesizedControllerPanel    matlab.ui.container.Panel
        NumeratorEditField_2Label     matlab.ui.control.Label
        NumeratorEditField_2          matlab.ui.control.EditField
        DenominatorEditField_2Label   matlab.ui.control.Label
        DenominatorEditField_2        matlab.ui.control.EditField
        StateSpaceTab                 matlab.ui.container.Tab
        ImplementationPanel_2         matlab.ui.container.Panel
        IntegerSlider_2Label          matlab.ui.control.Label
        IntegerSlider_2               matlab.ui.control.Slider
        FractionalSlider_2Label       matlab.ui.control.Label
        FractionalSlider_2            matlab.ui.control.Slider
        MaxRangeSpinner_2Label        matlab.ui.control.Label
        MaxRangeSpinner_2             matlab.ui.control.Spinner
        MinRangeSpinner_2Label        matlab.ui.control.Label
        MinRangeSpinner_2             matlab.ui.control.Spinner
        PlantPanel_2                  matlab.ui.container.Panel
        AEditFieldLabel               matlab.ui.control.Label
        AEditField                    matlab.ui.control.EditField
        BEditFieldLabel               matlab.ui.control.Label
        BEditField                    matlab.ui.control.EditField
        CEditFieldLabel               matlab.ui.control.Label
        CEditField                    matlab.ui.control.EditField
        DEditFieldLabel               matlab.ui.control.Label
        DEditField                    matlab.ui.control.EditField
        InputsEditFieldLabel          matlab.ui.control.Label
        InputsEditField               matlab.ui.control.EditField
        SynthesizeButton_2            matlab.ui.control.Button
        ResetButton_2                 matlab.ui.control.Button
        SynthesizedControllerPanel_2  matlab.ui.container.Panel
        KEditFieldLabel               matlab.ui.control.Label
        KEditField                    matlab.ui.control.EditField
        SimulationTab                 matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        ImpulseResponseButton         matlab.ui.control.Button
        BodeDiagramButton             matlab.ui.control.Button
        StepResponseButton            matlab.ui.control.Button
        ResetButton_3                 matlab.ui.control.Button
    end


    methods (Access = private)
    
        function [controller, system] = synthesizeTransferFunction(app)
            
            numerator = str2num(app.NumeratorEditField.Value);
            denominator = str2num(app.DenominatorEditField.Value);
            fracBits = app.IntegerSlider.Value;
            intBits = app.FractionalSlider.Value;
            sample = app.SampleTimeEditField.Value;
            system = tf(numerator, denominator, sample);
            
            minRange = app.MaxRangeSpinner.Value;
            maxRange = app.MinRangeSpinner.Value;
            
            controller = synthesize(system,intBits,fracBits, minRange, maxRange);
            
            app.NumeratorEditField_2.Value = num2str(cell2mat(controller.Numerator));
            app.DenominatorEditField_2.Value = num2str(cell2mat(controller.Denominator));
        end
        
    end


    methods (Access = private)

        % Button pushed function: ResetButton
        function ClearTransferFunctionCallback(app, event)
            app.NumeratorEditField.Value = '';
            app.DenominatorEditField.Value = '';
            app.IntegerSlider.Value = 0;
            app.FractionalSlider.Value = 0;
            app.SampleTimeEditField.Value = 0;
            app.MaxRangeSpinner.Value = 0;
            app.MinRangeSpinner.Value = 0;
            app.NumeratorEditField_2.Value = '';
            app.DenominatorEditField_2.Value ='';
        end

        % Button pushed function: ResetButton_2
        function ClearStateSpaceCallback(app, event)
            app.IntegerSlider_2.Value = 0;
            app.FractionalSlider_2.Value = 0;
            app.AEditField.Value = '';
            app.BEditField.Value = '';
            app.CEditField.Value = '';
            app.DEditField.Value = '';
            app.InputsEditField.Value = '';
            app.StabilityCheckBox_2.Value = 0;
            app.SafetyCheckBox.Value = 0;
        end

        % Button pushed function: SynthesizeButton_3
        function SynthesizeButtonTransferFunction(app, event)
            global system;
            
            [controller, plant] = synthesizeTransferFunction(app);
            system = feedback(series(plant, controller),1);
        end

        % Button pushed function: ImpulseResponseButton
        function ImpulseResponseButtonPushed(app, event)
            global system;
            
            numerator = cell2mat(system.Numerator);
            denominator = cell2mat(system.Denominator);
            
            y = impz(numerator, denominator);
            plot(app.UIAxes,y);
        end

        % Button pushed function: StepResponseButton
        function StepResponseButtonPushed(app, event)
             global system;
            
            numerator = cell2mat(system.Numerator);
            denominator = cell2mat(system.Denominator);
            
            y = dstep(numerator,denominator);
            plot(app.UIAxes,y);
        end

        % Button pushed function: BodeDiagramButton
        function BodeDiagramButtonPushed(app, event)
              
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DSSynthApplicationUIFigure = uifigure;
            app.DSSynthApplicationUIFigure.Name = 'DSSynth Application';
            setAutoResize(app, app.DSSynthApplicationUIFigure, true)
            app.TabGroup = uitabgroup(app.DSSynthApplicationUIFigure);
            app.TransferFunctionTab = uitab(app.TabGroup);
            app.TransferFunctionTab.Title = 'Transfer-Function';
            app.TransferFunctionTab.Units = 'pixels';
            app.PlantPanel = uipanel(app.TransferFunctionTab);
            app.PlantPanel.Title = 'Plant';
            app.NumeratorEditFieldLabel = uilabel(app.PlantPanel);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.PlantPanel, 'text');
            app.DenominatorEditFieldLabel = uilabel(app.PlantPanel);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.PlantPanel, 'text');
            app.SampleTimeEditFieldLabel = uilabel(app.PlantPanel);
            app.SampleTimeEditFieldLabel.Text = 'Sample Time';
            app.SampleTimeEditField = uieditfield(app.PlantPanel, 'numeric');
            app.SampleTimeEditField.ValueDisplayFormat = '%.3f';
            app.ImplementationPanel = uipanel(app.TransferFunctionTab);
            app.ImplementationPanel.Title = 'Implementation';
            app.IntegerSliderLabel = uilabel(app.ImplementationPanel);
            app.IntegerSliderLabel.Text = 'Integer';
            app.IntegerSlider = uislider(app.ImplementationPanel);
            app.IntegerSlider.Limits = [0 32];
            app.IntegerSlider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.FractionalSliderLabel = uilabel(app.ImplementationPanel);
            app.FractionalSliderLabel.Text = 'Fractional';
            app.FractionalSlider = uislider(app.ImplementationPanel);
            app.FractionalSlider.Limits = [0 32];
            app.FractionalSlider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.MaxRangeSpinnerLabel = uilabel(app.ImplementationPanel);
            app.MaxRangeSpinnerLabel.Text = 'Max. Range';
            app.MaxRangeSpinner = uispinner(app.ImplementationPanel);
            app.MinRangeSpinnerLabel = uilabel(app.ImplementationPanel);
            app.MinRangeSpinnerLabel.Text = 'Min Range';
            app.MinRangeSpinner = uispinner(app.ImplementationPanel);
            app.SynthesizeButton_3 = uibutton(app.TransferFunctionTab, 'push');
            app.SynthesizeButton_3.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeButtonTransferFunction, true);
            app.SynthesizeButton_3.Text = 'Synthesize';
            app.ResetButton = uibutton(app.TransferFunctionTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTransferFunctionCallback, true);
            app.ResetButton.Text = 'Reset';
            app.SynthesizedControllerPanel = uipanel(app.TransferFunctionTab);
            app.SynthesizedControllerPanel.Title = 'Synthesized Controller';
            app.NumeratorEditField_2Label = uilabel(app.SynthesizedControllerPanel);
            app.NumeratorEditField_2Label.Text = 'Numerator';
            app.NumeratorEditField_2 = uieditfield(app.SynthesizedControllerPanel, 'text');
            app.NumeratorEditField_2.Editable = 'off';
            app.DenominatorEditField_2Label = uilabel(app.SynthesizedControllerPanel);
            app.DenominatorEditField_2Label.Text = 'Denominator';
            app.DenominatorEditField_2 = uieditfield(app.SynthesizedControllerPanel, 'text');
            app.DenominatorEditField_2.Editable = 'off';
            app.StateSpaceTab = uitab(app.TabGroup);
            app.StateSpaceTab.Title = 'State-Space';
            app.StateSpaceTab.Units = 'pixels';
            app.ImplementationPanel_2 = uipanel(app.StateSpaceTab);
            app.ImplementationPanel_2.Title = 'Implementation';
            app.IntegerSlider_2Label = uilabel(app.ImplementationPanel_2);
            app.IntegerSlider_2Label.Text = 'Integer';
            app.IntegerSlider_2 = uislider(app.ImplementationPanel_2);
            app.IntegerSlider_2.Limits = [0 32];
            app.IntegerSlider_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.FractionalSlider_2Label = uilabel(app.ImplementationPanel_2);
            app.FractionalSlider_2Label.Text = 'Fractional';
            app.FractionalSlider_2 = uislider(app.ImplementationPanel_2);
            app.FractionalSlider_2.Limits = [0 32];
            app.FractionalSlider_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32];
            app.MaxRangeSpinner_2Label = uilabel(app.ImplementationPanel_2);
            app.MaxRangeSpinner_2Label.Text = 'Max. Range';
            app.MaxRangeSpinner_2 = uispinner(app.ImplementationPanel_2);
            app.MinRangeSpinner_2Label = uilabel(app.ImplementationPanel_2);
            app.MinRangeSpinner_2Label.Text = 'Min Range';
            app.MinRangeSpinner_2 = uispinner(app.ImplementationPanel_2);
            app.PlantPanel_2 = uipanel(app.StateSpaceTab);
            app.PlantPanel_2.Title = 'Plant';
            app.AEditFieldLabel = uilabel(app.PlantPanel_2);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.PlantPanel_2, 'text');
            app.BEditFieldLabel = uilabel(app.PlantPanel_2);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.PlantPanel_2, 'text');
            app.CEditFieldLabel = uilabel(app.PlantPanel_2);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.PlantPanel_2, 'text');
            app.DEditFieldLabel = uilabel(app.PlantPanel_2);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.PlantPanel_2, 'text');
            app.InputsEditFieldLabel = uilabel(app.PlantPanel_2);
            app.InputsEditFieldLabel.Text = 'Inputs';
            app.InputsEditField = uieditfield(app.PlantPanel_2, 'text');
            app.SynthesizeButton_2 = uibutton(app.StateSpaceTab, 'push');
            app.SynthesizeButton_2.Text = 'Synthesize';
            app.ResetButton_2 = uibutton(app.StateSpaceTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearStateSpaceCallback, true);
            app.ResetButton_2.Text = 'Reset';
            app.SynthesizedControllerPanel_2 = uipanel(app.StateSpaceTab);
            app.SynthesizedControllerPanel_2.Title = 'Synthesized Controller';
            app.KEditFieldLabel = uilabel(app.SynthesizedControllerPanel_2);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.SynthesizedControllerPanel_2, 'text');
            app.KEditField.Editable = 'off';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationTab.Units = 'pixels';
            app.UIAxes = uiaxes(app.SimulationTab);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.ImpulseResponseButton = uibutton(app.SimulationTab, 'push');
            app.ImpulseResponseButton.ButtonPushedFcn = createCallbackFcn(app, @ImpulseResponseButtonPushed, true);
            app.ImpulseResponseButton.Text = 'Impulse Response';
            app.BodeDiagramButton = uibutton(app.SimulationTab, 'push');
            app.BodeDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @BodeDiagramButtonPushed, true);
            app.BodeDiagramButton.Text = 'Bode Diagram';
            app.StepResponseButton = uibutton(app.SimulationTab, 'push');
            app.StepResponseButton.ButtonPushedFcn = createCallbackFcn(app, @StepResponseButtonPushed, true);
            app.StepResponseButton.Text = 'Step Response';
            app.ResetButton_3 = uibutton(app.SimulationTab, 'push');
            app.ResetButton_3.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DSSynth()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSSynthApplicationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSSynthApplicationUIFigure)
        end
    end
end